classdef gauss < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Panel_4          matlab.ui.container.Panel
        TextArea         matlab.ui.control.TextArea
        Button_2         matlab.ui.control.Button
        EditField_3      matlab.ui.control.NumericEditField
        Label_7          matlab.ui.control.Label
        EditField_4      matlab.ui.control.NumericEditField
        Label_8          matlab.ui.control.Label
        Hyperlink        matlab.ui.control.Hyperlink
        CheckBox         matlab.ui.control.CheckBox
        Button           matlab.ui.control.Button
        Panel_3          matlab.ui.container.Panel
        yEditField       matlab.ui.control.NumericEditField
        yLabel           matlab.ui.control.Label
        xEditField       matlab.ui.control.NumericEditField
        xLabel           matlab.ui.control.Label
        Panel_2          matlab.ui.container.Panel
        LEditField       matlab.ui.control.NumericEditField
        LEditFieldLabel  matlab.ui.control.Label
        DropDown_3       matlab.ui.control.DropDown
        Label_3          matlab.ui.control.Label
        BEditField       matlab.ui.control.NumericEditField
        BEditFieldLabel  matlab.ui.control.Label
        DropDown_2       matlab.ui.control.DropDown
        Label_2          matlab.ui.control.Label
        EditField_5      matlab.ui.control.NumericEditField
        Label_9          matlab.ui.control.Label
        DropDown         matlab.ui.control.DropDown
        Label            matlab.ui.control.Label
    end

    methods (Access = private)
        function updateEllipsoidDisplay(app)
            switch app.DropDown.Value
                case 'CGCS2000'
                    a = 6378137.0;
                    invf = 298.257222101;
                case 'WGS84'
                    a = 6378137.0;
                    invf = 298.257223563;
                case 'IUGG75'
                    a = 6378140.0;
                    invf = 298.257;
                case 'Krasovsky'
                    a = 6378245.0;
                    invf = 298.3;
            end
            app.EditField_3.Value = a;
            app.EditField_4.Value = invf;
        end
        function rad = convert_angle(~, val, type)
            if type == 1
                rad = deg2rad(val);
            else
                d = floor(val);
                frac = round((val - d) * 10000);
                m = floor(frac / 100);
                s = mod(frac, 100);
                if m >= 60 || s >= 60
                    error('角度格式错误！');
                end
                rad = deg2rad(d + m/60 + s/3600);
            end
        end
        function s_str = rad2dms_str(~, rad)
            sgn = '';
            if rad < 0
                sgn = '-';
                rad = abs(rad);
            end
            val = rad2deg(rad);
            d = floor(val);
            m = floor((val - d) * 60);
            s = (val - d - m/60) * 3600;
            if s >= 59.999995
                s = 0;
                m = m + 1;
            end
            if m >= 60
                m = 0;
                d = d + 1;
            end
            s_str = sprintf('%s%d°%02d''%07.4f"', sgn, d, m, s);
        end
        function ellip = get_ellipsoid(~, idx)
            params = [6378137.0, 298.257222101;
                6378137.0, 298.257223563;
                6378140.0, 298.257;
                6378245.0, 298.3];
            ellip.a = params(idx, 1);
            f = 1 / params(idx, 2);
            ellip.e2 = 2*f - f^2;
            ellip.e12 = ellip.e2 / (1 - ellip.e2);
        end
        function [x, y_nat] = GaussForward(~, B, L, L0, ellip)
            a = ellip.a;
            e2 = ellip.e2;
            e12 = ellip.e12;
            l = L - L0;

            m0 = a*(1-e2);
            m2 = 1.5*e2*m0;
            m4 = 1.25*e2*m2;
            m6 = 7/6*e2*m4;
            m8 = 1.125*e2*m6;

            a0 = m0 + 0.5*m2 + 0.375*m4 + 0.3125*m6 + (35/128)*m8;
            a2 = 0.5*m2 + 0.5*m4 + (15/32)*m6 + 0.4375*m8;
            a4 = 0.125*m4 + 0.1875*m6 + (7/32)*m8;
            a6 = (1/32)*m6 + 0.0625*m8;
            a8 = (1/128)*m8;

            X = a0*B - a2*sin(2*B)/2 + a4*sin(4*B)/4 - a6*sin(6*B)/6 + a8*sin(8*B)/8;

            N = a / sqrt(1 - e2*sin(B)^2);
            t = tan(B);
            eta2 = e12*cos(B)^2;

            x = X + N*sin(B)*cos(B)*l^2/2 ...
                + N*sin(B)*cos(B)^3*(5 - t^2 + 9*eta2 + 4*eta2^2)*l^4/24 ...
                + N*sin(B)*cos(B)^5*(61 - 58*t^2 + t^4)*l^6/720;

            y_nat = N*cos(B)*l ...
                + N*cos(B)^3*(1 - t^2 + eta2)*l^3/6 ...
                + N*cos(B)^5*(5 - 18*t^2 + t^4 + 14*eta2 - 58*t^2*eta2)*l^5/120;
        end
        function [B, L, l] = GaussBackward(~, x, y_nat, L0_rad, ellip)
            a = ellip.a;
            e2 = ellip.e2;
            e12 = ellip.e12;
            y = y_nat;

            m0 = a*(1-e2);
            m2 = 1.5*e2*m0;
            m4 = 1.25*e2*m2;
            m6 = 7/6*e2*m4;
            m8 = 1.125*e2*m6;

            a0 = m0 + 0.5*m2 + 0.375*m4 + 0.3125*m6 + (35/128)*m8;
            a2 = 0.5*m2 + 0.5*m4 + (15/32)*m6 + 0.4375*m8;
            a4 = 0.125*m4 + 0.1875*m6 + (7/32)*m8;
            a6 = (1/32)*m6 + 0.0625*m8;
            a8 = (1/128)*m8;

            Bf = x / a0;
            while true
                FBf = -a2*sin(2*Bf)/2 + a4*sin(4*Bf)/4 - a6*sin(6*Bf)/6 + a8*sin(8*Bf)/8;
                Bf_new = (x - FBf) / a0;
                if abs(Bf_new - Bf) < 1e-12
                    break;
                end
                Bf = Bf_new;
            end
            Nf = a / sqrt(1 - e2*sin(Bf)^2);
            Mf = a*(1-e2) / (1-e2*sin(Bf)^2)^1.5;
            tf = tan(Bf);
            etaf2 = e12*cos(Bf)^2;
            B = Bf - tf*y^2/(2*Mf*Nf) ...
                + tf*(5 + 3*tf^2 + etaf2 - 9*etaf2*tf^2)*y^4/(24*Mf*Nf^3) ...
                - tf*(61 + 90*tf^2 + 45*tf^4)*y^6/(720*Mf*Nf^5);
            l = y/(Nf*cos(Bf)) ...
                - (1 + 2*tf^2 + etaf2)*y^3/(6*Nf^3*cos(Bf)) ...
                + (5 + 28*tf^2 + 24*tf^4 + 6*etaf2 + 8*etaf2*tf^2)*y^5/(120*Nf^5*cos(Bf));
            L = L0_rad + l;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TextArea.Value = {'填写参数后点击“计算”'};
            app.EditField_3.ValueDisplayFormat = '%.3f';
            app.EditField_4.ValueDisplayFormat = '%.12f';
            app.updateEllipsoidDisplay();
            app.CheckBox.Value = false;
            app.DropDown_2.Enable = 'on';
            app.DropDown_3.Enable = 'on';
            app.LEditField.Enable = 'on';
            app.BEditField.Enable = 'on';
            app.xEditField.Enable = 'off';
            app.yEditField.Enable = 'off';

            app.LEditField.Value = 0;
            app.BEditField.Value = 0;
            app.xEditField.Value = 0;
            app.yEditField.Value = 0;
            app.LEditField.UserData = false;
            app.BEditField.UserData = false;
            app.xEditField.UserData = false;
            app.yEditField.UserData = false;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.updateEllipsoidDisplay();
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            if app.CheckBox.Value
                app.DropDown_2.Enable = 'off';
                app.DropDown_3.Enable = 'off';
                app.LEditField.Enable = 'off';
                app.BEditField.Enable = 'off';

                app.xEditField.Enable = 'on';
                app.yEditField.Enable = 'on';
            else
                app.DropDown_2.Enable = 'on';
                app.DropDown_3.Enable = 'on';
                app.LEditField.Enable = 'on';
                app.BEditField.Enable = 'on';

                app.xEditField.Enable = 'off';
                app.yEditField.Enable = 'off';
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.TextArea.Value = {''};
            if ~app.CheckBox.Value   % 正算
                if ~app.LEditField.UserData || ~app.BEditField.UserData
                    app.TextArea.Value = {'错误：请输入经度 L 和纬度 B'};
                    return
                end
            else                     % 反算
                if ~app.xEditField.UserData || ~app.yEditField.UserData
                    app.TextArea.Value = {'错误：请输入平面坐标 x 和 y'};
                    return
                end
            end
            if app.CheckBox.Value
                mode = 2;
            else
                mode = 1;
            end
            switch app.DropDown.Value
                case 'CGCS2000'
                    e_idx = 1;
                case 'WGS84'
                    e_idx = 2;
                case 'IUGG75'
                    e_idx = 3;
                case 'Krasovsky'
                    e_idx = 4;
            end
            L0_deg = app.EditField_5.Value;
            L0_rad = deg2rad(L0_deg);

            if mode == 1
                % 高斯正算
                switch app.DropDown_2.Value
                    case '六度带'
                        band_type = 1;
                    case '三度带'
                        band_type = 2;
                end
                switch app.DropDown_3.Value
                    case '十进制格式'
                        type = 1;
                    case '度分秒格式（ddd.mmss)'
                        type = 2;
                end
                L_raw = app.LEditField.Value;
                B_raw = app.BEditField.Value;
                ellip = app.get_ellipsoid(e_idx);
                B_rad = app.convert_angle(B_raw, type);
                L_rad = app.convert_angle(L_raw, type);
                [x, y_nat] = app.GaussForward(B_rad, L_rad, L0_rad, ellip);
                y_500 = y_nat + 500000;
                if band_type == 1
                    zone = L0_deg / 6 + 0.5;
                else
                    zone = L0_deg / 3;
                end
                y_zone = zone * 1000000 + y_500;
                app.TextArea.Value = {
                    '计算结果'
                    ['1. 高斯平面纵坐标 x = ', num2str(x, '%.4f'), ' m']
                    ['2. 高斯平面横坐标 y (原始坐标) = ', num2str(y_nat, '%.4f'), ' m']
                    ['3. 高斯平面横坐标 y (平移500km) = ', num2str(y_500, '%.4f'), ' m']
                    ['4. 高斯平面横坐标 y (加带号) = ', num2str(y_zone, '%.4f'), ' m']
                    };
            else
                % 高斯反算
                x = app.xEditField.Value;
                y_in = app.yEditField.Value;
                ellip = app.get_ellipsoid(e_idx);

                if abs(y_in) < 500000
                    y_nat = y_in;
                    coord_type = '原始坐标(自然坐标)';
                    zone_no = NaN;
                elseif abs(y_in) < 1000000
                    y_nat = y_in - 500000;
                    coord_type = '平移500km坐标';
                    zone_no = NaN;
                else
                    zone_no = floor(abs(y_in) / 1000000);
                    y_prac = mod(abs(y_in), 1000000);
                    y_nat = y_prac - 500000;
                    if y_in < 0
                        y_nat = -y_nat;
                    end
                    coord_type = ['带号坐标(第', num2str(zone_no), '带)'];
                end
                [B_rad, L_rad, l_rad] = app.GaussBackward(x, y_nat, L0_rad, ellip);
                app.TextArea.Value = {
                    '计算结果'
                    ['坐标类型: ', coord_type]
                    ['还原后的自然坐标 y : ', num2str(y_nat, '%.4f'), ' m']
                    ['1. 经差 l : ', num2str(rad2deg(l_rad), '%.10f'), '°']
                    ['2. 经度 L : ', num2str(rad2deg(L_rad), '%.10f'), '°  (及度分秒: ', app.rad2dms_str(L_rad), ')']
                    ['3. 纬度 B : ', num2str(rad2deg(B_rad), '%.10f'), '°  (及度分秒: ', app.rad2dms_str(B_rad), ')']
                    };

            end
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            app.LEditField.UserData = true;
        end

        % Value changed function: BEditField
        function BEditFieldValueChanged(app, event)
            app.BEditField.UserData = true;
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            app.xEditField.UserData = true;
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            app.yEditField.UserData = true;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            fclose('all');
            [file, path] = uigetfile({'*.txt;*.csv','数据文件 (*.txt, *.csv)'}, '选择批量数据文件');
            if isequal(file, 0), return; end
            app.TextArea.Value = {'正在读取文件并开始计算...'};
            drawnow;
            try
                data = readtable(fullfile(path, file), 'ReadVariableNames', false, 'TextType', 'string');
                numPoints = height(data);
                results = cell(numPoints, 6);
                isBackward = app.CheckBox.Value;
                for i = 1:numPoints
                    name      = data{i, 1};
                    ellip_str = char(data{i, 2});
                    L0_deg    = data{i, 3};
                    val1      = data{i, 4};
                    val2      = data{i, 5};
                    switch ellip_str
                        case 'CGCS2000', e_idx = 1;
                        case 'WGS84',    e_idx = 2;
                        case 'IUGG75',   e_idx = 3;
                        case 'Krasovsky', e_idx = 4;
                        otherwise, error(['行 ', num2str(i), ': 椭球名称无效']);
                    end
                    ellip = app.get_ellipsoid(e_idx);
                    L0_rad = deg2rad(L0_deg);
                    if ~isBackward
                        % 正算
                        [x, y_nat] = app.GaussForward(deg2rad(val2), deg2rad(val1), L0_rad, ellip);
                        y_500 = y_nat + 500000;
                        if string(app.DropDown_2.Value) == "六度带"
                            zone = L0_deg / 6 + 0.5;
                        else
                            zone = L0_deg / 3;
                        end
                        y_zone = zone * 1000000 + y_500;
                        results(i, :) = {name, ellip_str, x, y_nat, y_500, y_zone};
                    else
                        % 反算
                        y_in = val2;
                        if abs(y_in) > 1000000
                            y_calc = mod(abs(y_in), 1000000) - 500000;
                        elseif abs(y_in) > 100000
                            y_calc = y_in - 500000;
                        else
                            y_calc = y_in;
                        end
                        [B_rad, L_rad, ~] = app.GaussBackward(val1, y_calc, L0_rad, ellip);
                        results(i, 1:4) = {name, ellip_str, rad2deg(L_rad), rad2deg(B_rad)};
                    end
                end
                outName = ['Result_', char(file)];
                fid = fopen(fullfile(path, outName), 'w');

                if ~isBackward
                    fprintf(fid, '%-10s | %-10s | %-15s | %-15s | %-15s | %-15s\n', ...
                        '点名', '椭球', 'x坐标', 'y_原始', 'y_平移', 'y_带号');
                    fprintf(fid, '%s\n', repmat('-', 1, 95));
                    for j = 1:numPoints
                        fprintf(fid, '%-10s | %-10s | %-15.4f | %-15.4f | %-15.4f | %-15.4f\n', ...
                            char(results{j,1}), char(results{j,2}), results{j,3}, results{j,4}, results{j,5}, results{j,6});
                    end
                else
                    fprintf(fid, '%-10s | %-10s | %-18s | %-18s\n', '点名', '椭球', '经度L', '纬度B');
                    fprintf(fid, '%s\n', repmat('-', 1, 65));
                    for j = 1:numPoints
                        fprintf(fid, '%-10s | %-10s | %-18.8f | %-18.8f\n', ...
                            char(results{j,1}), char(results{j,2}), results{j,3}, results{j,4});
                    end
                end
                fclose(fid);

                app.TextArea.Value = {'计算成功！结果已保存。'};
                uialert(app.UIFigure, ['文件已生成：', outName], '完成');
                winopen(path);
            catch ME
                if exist('fid', 'var') && fid ~= -1, fclose(fid); end
                uialert(app.UIFigure, ['出错原因：', ME.message], '错误');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '参考椭球：';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'CGCS2000', 'WGS84', 'IUGG75', 'Krasovsky'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'CGCS2000';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '中央子午线：';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Value = 120;
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '高斯正算输入';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '投影分带';
            app.DropDown_2 = uidropdown(app.Panel_2);
            app.DropDown_2.Items = {'三度带', '六度带'};
            app.DropDown_2.Value = '三度带';
            app.BEditFieldLabel = uilabel(app.Panel_2);
            app.BEditFieldLabel.Text = '纬度B';
            app.BEditField = uieditfield(app.Panel_2, 'numeric');
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @BEditFieldValueChanged, true);
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '输入格式';
            app.DropDown_3 = uidropdown(app.Panel_2);
            app.DropDown_3.Items = {'十进制格式', '度分秒格式（ddd.mmss)'};
            app.DropDown_3.Value = '十进制格式';
            app.LEditFieldLabel = uilabel(app.Panel_2);
            app.LEditFieldLabel.Text = '经度L';
            app.LEditField = uieditfield(app.Panel_2, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '高斯反算输入';
            app.xLabel = uilabel(app.Panel_3);
            app.xLabel.Text = '平面坐标x';
            app.xEditField = uieditfield(app.Panel_3, 'numeric');
            app.xEditField.ValueDisplayFormat = '%.4f';
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yLabel = uilabel(app.Panel_3);
            app.yLabel.Text = '平面坐标y';
            app.yEditField = uieditfield(app.Panel_3, 'numeric');
            app.yEditField.ValueDisplayFormat = '%.4f';
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '计算';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '勾选反算';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'hpucz2301.netlify.app';
            app.Hyperlink.Text = {'高'; '斯'; '投'; '影'; '计'; '算'; '程'; '序'};
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '扁率倒数:';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueDisplayFormat = '%.10f';
            app.EditField_4.Editable = 'off';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '椭球长半径：';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueDisplayFormat = '%.1f';
            app.EditField_3.Editable = 'off';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '批量文件计算';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = '计算结果：';
            app.TextArea = uitextarea(app.Panel_4);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gauss

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end